classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PaszczyznastrzakowaTab        matlab.ui.container.Tab
        WszytanieobrazwPanel          matlab.ui.container.Panel
        wczytajS                      matlab.ui.control.Button
        lampkaWS                      matlab.ui.control.Lamp
        Rekonstrukcjamodelu3DPanel    matlab.ui.container.Panel
        lampkaZS                      matlab.ui.control.Lamp
        zlozS                         matlab.ui.control.Button
        modelS                        matlab.ui.control.Image
        SegmentacjaPanel              matlab.ui.container.Panel
        segAutoS                      matlab.ui.control.Button
        lampkaAutoS                   matlab.ui.control.Lamp
        ImageS                        matlab.ui.control.Image
        segRecznieS                   matlab.ui.control.Button
        lampkaRecznieS                matlab.ui.control.Lamp
        PaszczyznapoprzecznaTab       matlab.ui.container.Tab
        WczytanieobrazwPanel_2        matlab.ui.container.Panel
        wczytajP                      matlab.ui.control.Button
        lampkaWP                      matlab.ui.control.Lamp
        Rekonstrukcjamodelu3DPanel_2  matlab.ui.container.Panel
        lampkaZP                      matlab.ui.control.Lamp
        zlozP                         matlab.ui.control.Button
        modelP                        matlab.ui.control.Image
        SegmentacjaPanel_2            matlab.ui.container.Panel
        segAutoP                      matlab.ui.control.Button
        lampkaAutoP                   matlab.ui.control.Lamp
        ImageP                        matlab.ui.control.Image
        segRecznieP                   matlab.ui.control.Button
        lampkaRecznieP                matlab.ui.control.Lamp
        SegmentacjamzgunapodstawiezdjrezonansumagnetycznegoLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: wczytajS
        function wczytajSPushed(app, event)
            try
                strzalkowa = uigetfile('*.dcm','Select One or More Files','MultiSelect', 'on');
                save('strzalkowa.mat',"strzalkowa")
                
                app.lampkaWS.Color = [0.39,0.83,0.07];
                
            catch
                msgbox('Wystąpił błąd podczas wczytywania obrazów!','Error','error')
                app.lampkaWS.Color = [1.00,0.41,0.16];
       
            end
           
        end

        % Button pushed function: wczytajP
        function wczytajPButtonPushed(app, event)
            try
                poprzeczna = uigetfile('*.dcm','Select One or More Files','MultiSelect', 'on');
                save('poprzeczna.mat',"poprzeczna")
                
                app.lampkaWP.Color = [0.39,0.83,0.07];
                
            catch
                msgbox('Wystąpił błąd podczas wczytywania obrazów!','Error','error')
                app.lampkaWP.Color = [1.00,0.41,0.16];
       
            end
        end

        % Button pushed function: segAutoS
        function segAutoSButtonPushed(app, event)
            try
                load('strzalkowa');
                [~,b] = size(strzalkowa);
            catch
                msgbox('Wystąpił błąd - obrazy nie zostały wczytane poprawnie','Error','error')
            end
            
            try  
            for i=1:b
                filename = strzalkowa{:,i};
                OBRAZ = dicomread(filename);
                I(:,:)=double(OBRAZ);

                if(i==1) 
                % dla pierwszego obrazu wybór punktów ręcznie    
                figure('Name','Wybór punktów')
                imshow(I,[])
                title('Obraz MRI mózgu')
                % punkt należący do struktury
                [xo,yo]=ginput(1);
                xo=round(xo);
                yo=round(yo);
                
                hold on; 
                p=plot(xo,yo,'*');
                set(p,'MarkerSize',10);
                set(p,'Color','g');
                
                % punkt nienależący do struktury
                [xp,yp]=ginput(1);
                xp=round(xp);
                yp=round(yp);
                
                hold on;
                p=plot(xp,yp,'*');
                set(p,'MarkerSize',10);
                set(p,'Color','b');
                
                else  
                    
                %dla każdego kolejnego obrazu - na podstawie centroidu poprzedniego
                xo = round(centX);
                yo = round(centY);
                    
                xp = 1;
                yp = 1;
                
                end
                
                data=reshape(I,[],1);
                
                % określenie liczby klas 
                klasa_obszaru=1;
                klasa_pozaobszarem=1;
                ilosc_klas=2;
                
                while (klasa_obszaru==klasa_pozaobszarem)
                    % grupowanie fcm - fuzzy c-means clustering
                    [center,U,~]=fcm(data,ilosc_klas,[NaN NaN NaN 0]);
                    [~,nrklasy]= max(U',[],2);
                    I_klasy=zeros(size(I));
                    I_nr_klas=zeros(size(I));
                    maxnrklasy=max(nrklasy);
                    for j=1:maxnrklasy
                        idx=find(nrklasy==j);
                        I_klasy(idx)=(ones(length(idx),1)*center(j));
                        I_nr_klas(idx)=j;
                    end    
                    klasa_obszaru=I_nr_klas(yo,xo);
                    klasa_pozaobszarem=I_nr_klas(yp,xp);
                    ilosc_klas=ilosc_klas+1;
                    if ilosc_klas>5 %warunek zakończenia
                        break;
                    end
                end
                
                idx=find(I_nr_klas==klasa_obszaru);

                macierz=zeros(size(I));
                macierz(idx)=1;

                wiersz=[xo xo];
                kolumna=[yo yo];
                
                % wysegmentowany obraz
                wynikSegmentacji=bwselect(macierz,wiersz,kolumna,4);
                
                % wyświetlenie wysegmentowanej struktury
                figure('Name','Wynik segmentacji')
                imshow(wynikSegmentacji); 
                title('Wysegmentowana strurktura mózgu');
                % przygotowanie do zapisu wysegmentowanej struktury jako
                % plik .png
                segm_file = [num2str(i),'.png'];
                imwrite(wynikSegmentacji,segm_file);
                close
                
                % obliczenie centroidu wysegmentowanej struktury
                stats = regionprops(wynikSegmentacji);
                centroid = stats.Centroid;
                % dostanie się do współrzędnych X i Y centroidu 
                centX = centroid(1,1);
                centY = centroid(1,2);
                
                app.ImageS.ImageSource = segm_file;
                
                app.lampkaAutoS.Color = [0.39,0.83,0.07];
  
            end 
            catch
                 msgbox('Wystąpił błąd podczas segmentacji!','Error','error')
                 app.lampkaAutoS.Color = [1.00,0.41,0.16];
            end
            close all
        end

        % Button pushed function: segAutoP
        function segAutoPButtonPushed(app, event)
            try
                load('poprzeczna');
                [~,b] = size(poprzeczna);
            catch
                msgbox('Wystąpił błąd - obrazy nie zostały wczytane poprawnie','Error','error')
            end
            
            try  
            for i=1:b
                filename = poprzeczna{:,i};
                OBRAZ = dicomread(filename);
                I(:,:)=double(OBRAZ);
                
                if(i==1)
                figure('Name','Wybór punktów')
                imshow(I,[])
                title('Obraz MRI mózgu')
                
                [xo,yo]=ginput(1);
                xo=round(xo);
                yo=round(yo);
                
                hold on; 
                p=plot(xo,yo,'*');
                set(p,'MarkerSize',10);
                set(p,'Color','g');
                
                [xp,yp]=ginput(1);
                xp=round(xp);
                yp=round(yp);
                
                hold on;
                p=plot(xp,yp,'*');
                set(p,'MarkerSize',10);
                set(p,'Color','b');
                pause(1);
                end
                
                if(i>1)
                    xo = round(centX);
                    yo = round(centY);
                    
                    xp = 1;
                    yp = 1;
                
                end
                
                data=reshape(I,[],1);
                
                klasa_obszaru=1;
                klasa_pozaobszarem=1;
                ilosc_klas=2;
                
                
                while (klasa_obszaru==klasa_pozaobszarem)
                    % grupowanie fcm - fuzzy c-means clustering
                    [center,U,~]=fcm(data,ilosc_klas,[NaN NaN NaN 0]);
                    [~,nrklasy]= max(U',[],2);
                    I_klasy=zeros(size(I));
                    I_nr_klas=zeros(size(I));
                    maxnrklasy=max(nrklasy);
                    for j=1:maxnrklasy
                        idx=find(nrklasy==j);
                        I_klasy(idx)=(ones(length(idx),1)*center(j));
                        I_nr_klas(idx)=j;
                    end    
                    klasa_obszaru=I_nr_klas(yo,xo);
                    klasa_pozaobszarem=I_nr_klas(yp,xp);
                    ilosc_klas=ilosc_klas+1;
                    if ilosc_klas>5 
                        break;
                    end
                end
                
                idx=find(I_nr_klas==klasa_obszaru);

                macierz=zeros(size(I));
                macierz(idx)=1;

                wiersz=[xo xo];
                kolumna=[yo yo];
                %wysegmentowany obraz
                wynikSegmentacji=bwselect(macierz,wiersz,kolumna,4); 
                    
                figure('Name','Wynik segmentacji')
                imshow(wynikSegmentacji); 
                title('Wysegmentowana strurktura mózgu');
                segm_file = ['p',num2str(i),'.png'];
                imwrite(wynikSegmentacji,segm_file);
                close
                
                stats = regionprops(wynikSegmentacji);
                centroid = stats.Centroid;
                centX = centroid(1,1);
                centY = centroid(1,2);
                
                app.ImageP.ImageSource = segm_file;
                
                app.lampkaAutoP.Color = [0.39,0.83,0.07];

            end
            catch
                 msgbox('Wystąpił błąd podczas segmentacji!','Error','error')
                 app.lampkaAutoP.Color = [1.00,0.41,0.16];
            end
            close all
        end

        % Button pushed function: zlozS
        function zlozSButtonPushed(app, event)
            %close all;
            try
                load('strzalkowa');
                [~,b] = size(strzalkowa);
            catch
                msgbox('Wystąpił nieoczekiwany błąd','Error','error')
            end
            
            try
            for i=1:b
                if i==1
                    nazwapliku = sprintf('%d', i);
                    obraz = [nazwapliku, '.png'];
                    data = imread(obraz);
                else
                    nazwapliku = sprintf('%d', i);
                    obraz = [nazwapliku, '.png'];
                    obraz2 = imread(obraz);
                    data = cat(3,data,obraz2);
                end
            end
            catch
                msgbox('Wystąpił błąd podczas wczytywania zdjęć struktur po rekonstrukcji!','Error','error')
            end

            try
            img = figure;
            H = vol3d('CData',data);
            [x, y, ~] = size(data);
            axis tight;
            view(3);
            daspect([x y 130])
            alphamap('decrease')
            colormap([0.30 0.74 0.93])
            alphamap(0.02 .* alphamap);
            
            %zastępstwo za wyświetlanie modelu w axes
            saveas(img,'modelS','jpg')
            %close(img);
            app.modelS.ImageSource = imread('modelS.jpg');
            
            app.lampkaZS.Color = [0.39,0.83,0.07];
            
            catch
                msgbox('Wystąpił błąd podczas rekonstrukcji!','Error','error')
                app.lampkaZS.Color = [1.00,0.41,0.16];
            end
            
        end

        % Button pushed function: zlozP
        function zlozPButtonPushed(app, event)
            try
                load('poprzeczna');
                [~,b] = size(poprzeczna);
            catch
                msgbox('Wystąpił nieoczekiwany błąd','Error','error')
            end
            
            try
            for i=1:b
                if i==1
                    nazwapliku = sprintf('p%d', i);
                    obraz = [nazwapliku, '.png'];
                    data = imread(obraz);
                else
                    nazwapliku = sprintf('p%d', i);
                    obraz = [nazwapliku, '.png'];
                    obraz2 = imread(obraz);
                    data = cat(3,data,obraz2);
                end
            end
            catch
                msgbox('Wystąpił błąd podczas wczytywania zdjęć struktur po rekonstrukcji!','Error','error')
            end

           try
            img = figure;
            H = vol3d('CData',data);
            [x, y, ~] = size(data);
            axis tight;
            view(3);
            daspect([x y 21])
            alphamap('decrease')
            colormap([0.30 0.74 0.93])
            alphamap(0.02 .* alphamap);
            
            %zastępstwo za wyświetlanie modelu w axes
            saveas(img,'modelP','jpg')
            %close(img);
            app.modelP.ImageSource = imread('modelP.jpg');
            
            app.lampkaZP.Color = [0.39,0.83,0.07];
            
           catch
                msgbox('Wystąpił błąd podczas rekonstrukcji!','Error','error')
                app.lampkaZP.Color = [1.00,0.41,0.16];
            end
        end

        % Button pushed function: segRecznieS
        function segRecznieSButtonPushed(app, event)
            try
                load('strzalkowa');
                [~,b] = size(strzalkowa);
            catch
                msgbox('Wystąpił błąd - obrazy nie zostały wczytane poprawnie','Error','error')
            end
            
            try  
            for i=1:b
                filename = strzalkowa{:,i};
                OBRAZ = dicomread(filename);
                I(:,:)=double(OBRAZ);
                figure('Name','Wybór punktów')
                imshow(I,[])
                title('Obraz MRI mózgu')
                
                data=reshape(I,[],1);
                
                [xo,yo]=ginput(1);
                xo=round(xo);
                yo=round(yo);
                
                hold on; 
                p=plot(xo,yo,'*');
                set(p,'MarkerSize',10);
                set(p,'Color','g');
                
                [xp,yp]=ginput(1);
                xp=round(xp);
                yp=round(yp);
                
                hold on;
                p=plot(xp,yp,'*');
                set(p,'MarkerSize',10);
                set(p,'Color','b');
                pause(1);
                
                klasa_obszaru=1;
                klasa_pozaobszarem=1;
                ilosc_klas=2;
                
                
                while (klasa_obszaru==klasa_pozaobszarem)
                    % grupowanie fcm - fuzzy c-means clustering
                    [center,U,~]=fcm(data,ilosc_klas,[NaN NaN NaN 0]);
                    [~,nrklasy]= max(U',[],2);
                    I_klasy=zeros(size(I));
                    I_nr_klas=zeros(size(I));
                    maxnrklasy=max(nrklasy);
                    for j=1:maxnrklasy
                        idx=find(nrklasy==j);
                        I_klasy(idx)=(ones(length(idx),1)*center(j));
                        I_nr_klas(idx)=j;
                    end    
                    klasa_obszaru=I_nr_klas(yo,xo);
                    klasa_pozaobszarem=I_nr_klas(yp,xp);
                    ilosc_klas=ilosc_klas+1;
                    if ilosc_klas>5 
                        break;
                    end
                end
                
                idx=find(I_nr_klas==klasa_obszaru);

                macierz=zeros(size(I));
                macierz(idx)=1;
                
                wiersz=[xo xo];
                kolumna=[yo yo];
                %wysegmentowany obraz
                wynikSegmentacji=bwselect(macierz,wiersz,kolumna,4);
                    
                figure
                imshow(wynikSegmentacji); 
                title('Wysegmentowana strurktura mózgu');
                segm_file = [num2str(i),'.png'];
                imwrite(wynikSegmentacji,segm_file);
                close
                
                app.ImageS.ImageSource = segm_file;
                
                app.lampkaRecznieS.Color = [0.39,0.83,0.07];
                
                
            end 
            catch
                 msgbox('Wystąpił błąd podczas segmentacji!','Error','error')
                 app.lampkaRecznieS.Color = [1.00,0.41,0.16];
            end
        end

        % Button pushed function: segRecznieP
        function segReczniePButtonPushed(app, event)
            try
                load('poprzeczna');
                [~,b] = size(poprzeczna);
            catch
                msgbox('Wystąpił błąd - obrazy nie zostały wczytane poprawnie','Error','error')
            end
            
            try  
            for i=1:b
                filename = poprzeczna{:,i};
                OBRAZ = dicomread(filename);
                I(:,:)=double(OBRAZ);
                figure('Name','Wybór punktów')
                imshow(I,[])
                title('Obraz MRI mózgu')
                
                data=reshape(I,[],1);
                
                [xo,yo]=ginput(1);
                xo=round(xo);
                yo=round(yo);
                
                hold on; 
                p=plot(xo,yo,'*');
                set(p,'MarkerSize',10);
                set(p,'Color','g');
                
                [xp,yp]=ginput(1);
                xp=round(xp);
                yp=round(yp);
                
                hold on;
                p=plot(xp,yp,'*');
                set(p,'MarkerSize',10);
                set(p,'Color','b');
                pause(1);
                
                klasa_obszaru=1;
                klasa_pozaobszarem=1;
                ilosc_klas=2;
                
                
                while (klasa_obszaru==klasa_pozaobszarem)
                    % grupowanie fcm - fuzzy c-means clustering
                    [center,U,~]=fcm(data,ilosc_klas,[NaN NaN NaN 0]);
                    [~,nrklasy]= max(U',[],2);
                    I_klasy=zeros(size(I));
                    I_nr_klas=zeros(size(I));
                    maxnrklasy=max(nrklasy);
                    for j=1:maxnrklasy
                        idx=find(nrklasy==j);
                        I_klasy(idx)=(ones(length(idx),1)*center(j));
                        I_nr_klas(idx)=j;
                    end    
                    klasa_obszaru=I_nr_klas(yo,xo);
                    klasa_pozaobszarem=I_nr_klas(yp,xp);
                    ilosc_klas=ilosc_klas+1;
                    if ilosc_klas>5 
                        break;
                    end
                end
                
                idx=find(I_nr_klas==klasa_obszaru);

                macierz=zeros(size(I));
                macierz(idx)=1;

                wiersz=[xo xo];
                kolumna=[yo yo];
                
                %wysegmentowany obraz
                wynikSegmentacji=bwselect(macierz,wiersz,kolumna,4);
                    
                figure
                imshow(wynikSegmentacji); 
                title('Wysegmentowana strurktura mózgu');
                segm_file = ['p',num2str(i),'.png'];
                imwrite(wynikSegmentacji,segm_file);
                close
                
                app.ImageP.ImageSource = segm_file;
                
                app.lampkaRecznieP.Color = [0.39,0.83,0.07];
                
                
            end 
            catch
                 msgbox('Wystąpił błąd podczas segmentacji!','Error','error')
                 app.lampkaRecznieP.Color = [1.00,0.41,0.16];
            end
            close all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PaszczyznastrzakowaTab = uitab(app.TabGroup);
            app.PaszczyznastrzakowaTab.Title = 'Płaszczyzna strzałkowa';
            app.WszytanieobrazwPanel = uipanel(app.PaszczyznastrzakowaTab);
            app.WszytanieobrazwPanel.Title = 'Wszytanie obrazów';
            app.wczytajS = uibutton(app.WszytanieobrazwPanel, 'push');
            app.wczytajS.ButtonPushedFcn = createCallbackFcn(app, @wczytajSPushed, true);
            app.wczytajS.Text = 'Wybierz i wczytaj zdjęcia typu DICOM';
            app.lampkaWS = uilamp(app.WszytanieobrazwPanel);
            app.Rekonstrukcjamodelu3DPanel = uipanel(app.PaszczyznastrzakowaTab);
            app.Rekonstrukcjamodelu3DPanel.Title = 'Rekonstrukcja modelu 3D';
            app.lampkaZS = uilamp(app.Rekonstrukcjamodelu3DPanel);
            app.zlozS = uibutton(app.Rekonstrukcjamodelu3DPanel, 'push');
            app.zlozS.ButtonPushedFcn = createCallbackFcn(app, @zlozSButtonPushed, true);
            app.zlozS.Text = 'Złóż model';
            app.modelS = uiimage(app.Rekonstrukcjamodelu3DPanel);
            app.modelS.ImageSource = '3d.png';
            app.SegmentacjaPanel = uipanel(app.PaszczyznastrzakowaTab);
            app.SegmentacjaPanel.Title = 'Segmentacja';
            app.segAutoS = uibutton(app.SegmentacjaPanel, 'push');
            app.segAutoS.ButtonPushedFcn = createCallbackFcn(app, @segAutoSButtonPushed, true);
            app.segAutoS.Text = 'Segmentuj automatycznie';
            app.lampkaAutoS = uilamp(app.SegmentacjaPanel);
            app.ImageS = uiimage(app.SegmentacjaPanel);
            app.ImageS.ImageSource = 'strzalkowa.png';
            app.segRecznieS = uibutton(app.SegmentacjaPanel, 'push');
            app.segRecznieS.ButtonPushedFcn = createCallbackFcn(app, @segRecznieSButtonPushed, true);
            app.segRecznieS.Text = 'Segmentuj ręcznie';
            app.lampkaRecznieS = uilamp(app.SegmentacjaPanel);
            app.PaszczyznapoprzecznaTab = uitab(app.TabGroup);
            app.PaszczyznapoprzecznaTab.Title = 'Płaszczyzna poprzeczna';
            app.WczytanieobrazwPanel_2 = uipanel(app.PaszczyznapoprzecznaTab);
            app.WczytanieobrazwPanel_2.Title = 'Wszytanie obrazów';
            app.wczytajP = uibutton(app.WczytanieobrazwPanel_2, 'push');
            app.wczytajP.ButtonPushedFcn = createCallbackFcn(app, @wczytajPButtonPushed, true);
            app.wczytajP.Text = 'Wybierz i wczytaj zdjęcia typu DICOM';
            app.lampkaWP = uilamp(app.WczytanieobrazwPanel_2);
            app.Rekonstrukcjamodelu3DPanel_2 = uipanel(app.PaszczyznapoprzecznaTab);
            app.Rekonstrukcjamodelu3DPanel_2.Title = 'Rekonstrukcja modelu 3D';
            app.lampkaZP = uilamp(app.Rekonstrukcjamodelu3DPanel_2);
            app.zlozP = uibutton(app.Rekonstrukcjamodelu3DPanel_2, 'push');
            app.zlozP.ButtonPushedFcn = createCallbackFcn(app, @zlozPButtonPushed, true);
            app.zlozP.Text = 'Złóż model';
            app.modelP = uiimage(app.Rekonstrukcjamodelu3DPanel_2);
            app.modelP.ImageSource = '3d.png';
            app.SegmentacjaPanel_2 = uipanel(app.PaszczyznapoprzecznaTab);
            app.SegmentacjaPanel_2.Title = 'Segmentacja';
            app.segAutoP = uibutton(app.SegmentacjaPanel_2, 'push');
            app.segAutoP.ButtonPushedFcn = createCallbackFcn(app, @segAutoPButtonPushed, true);
            app.segAutoP.Text = 'Segmentuj automatycznie';
            app.lampkaAutoP = uilamp(app.SegmentacjaPanel_2);
            app.ImageP = uiimage(app.SegmentacjaPanel_2);
            app.ImageP.ImageSource = 'poprzeczna.png';
            app.segRecznieP = uibutton(app.SegmentacjaPanel_2, 'push');
            app.segRecznieP.ButtonPushedFcn = createCallbackFcn(app, @segReczniePButtonPushed, true);
            app.segRecznieP.Text = 'Segmentuj ręcznie';
            app.lampkaRecznieP = uilamp(app.SegmentacjaPanel_2);
            app.SegmentacjamzgunapodstawiezdjrezonansumagnetycznegoLabel = uilabel(app.UIFigure);
            app.SegmentacjamzgunapodstawiezdjrezonansumagnetycznegoLabel.Text = 'Segmentacja mózgu na podstawie zdjęć rezonansu magnetycznego';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end